classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Image                  matlab.ui.control.Image
        OrdenEditField         matlab.ui.control.EditField
        OrdenEditFieldLabel    matlab.ui.control.Label
        DoEditField            matlab.ui.control.EditField
        DoEditFieldLabel       matlab.ui.control.Label
        TamaoLabel             matlab.ui.control.Label
        HazloButton            matlab.ui.control.Button
        Labelimag              matlab.ui.control.Label
        AccionesDropDown       matlab.ui.control.DropDown
        AccionesDropDownLabel  matlab.ui.control.Label
        AbririmagenButton      matlab.ui.control.Button
    end

    
    properties (Access = public)
        imagen % Description
        imagenname
        FTTObject % Description
     
       
    end
    
    methods (Access = public)
        
        function doOption(app)
            
            valorActual = app.AccionesDropDown.Value;
            
            if(strcmp(valorActual,"Compresion FFT")==1)
                %disp(valorActual);
                app.FTTObject = ImageCompresor(app.imagenname);
                app.FTTObject.doCompression();
            end
            
            if(strcmp(valorActual,"Butterworth")==1)
                
                I = imread(app.imagenname);
                I = im2gray(I);
                [N,M] = size(I);
                %Frec corte
                Do = str2double(app.DoEditField.Value);
                orden = str2double(app.OrdenEditField.Value);
            
                %calcular la mascara para el filtro
                H = ButterworthMask(N,M,Do,orden);
                
                J = FiltroFrecuencia(I,H,1);
           
              
            end


            if(strcmp(valorActual,"Gaussiano") == 1)

                I = imread(app.imagenname);
                I = im2gray(I);
                [N,M] = size(I);
                %Frec corte
                Do = str2double(app.DoEditField.Value);
                orden = str2double(app.OrdenEditField.Value);

                %calcular la mascara para el filtro
                H = GaussianMask(N,M,Do);

                J = FiltroFrecuencia(I,H,1);
                
            end
            
            if(strcmp(valorActual,"Pasabajos ideal")==1)
                
                I = imread(app.imagenname);
                I = im2gray(I);
                [N,M] = size(I);
                %Frec corte
                Do = str2double(app.DoEditField.Value);
                orden = str2double(app.OrdenEditField.Value);
            
                %calcular la mascara para el filtro
                H = IdealMask(N,M,Do);
             
                J = FiltroFrecuencia(I,H,1);


            end
         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AbririmagenButton
        function btnAbrir(app, event)
            char name;
            [name,pathname] = uigetfile({'*.jpg';'*.bmp';'*.gif'},'Select file');
            
            app.imagenname = sprintf('%s%s',pathname,name);
            app.imagen = imread(app.imagenname);
     
            [f,c] = size(app.imagen);
            cadSize = '';
            cadSize = strcat(cadSize,num2str(f));
            cadSize = strcat(cadSize,'x');
            cadSize = strcat(cadSize,num2str(c));
            
            app.Image.ImageSource = app.imagen;
            app.AccionesDropDownLabel.Visible = 'on';
            app.AccionesDropDown.Visible = 'on';
            app.Labelimag.Visible = 'on';
            app.Labelimag.Text = app.imagenname;
            app.HazloButton.Visible = 'on';
            app.TamaoLabel.Text = cadSize;
            app.TamaoLabel.Visible = 'on';


        end

        % Button pushed function: HazloButton
        function HazAction(app, event)
            app.doOption();
           
        end

        % Value changed function: AccionesDropDown
        function cambiaOpcion(app, event)
            value = app.AccionesDropDown.Value;
            
            if(strcmp(value,"Pasabajos ideal") == 1)
                
                app.DoEditField.Visible = "on";
                app.DoEditFieldLabel.Visible = "on";
                app.DoEditFieldLabel.Visible = "on";
                
            end
            
             if(strcmp(value,"Gaussiano") == 1)
                
                app.DoEditField.Visible = "on";
                app.DoEditFieldLabel.Visible = "on";
                app.DoEditFieldLabel.Visible = "off";
                
            end
            
            if(strcmp(value,"Compresion FFT") == 1)
                app.DoEditField.Visible = "off";
                app.DoEditFieldLabel.Visible = "off";
                app.OrdenEditField.Visible = "off";
                app.OrdenEditFieldLabel.Visible = "off";
            
            end
            
            if(strcmp(value,"Butterworth") == 1)
                app.DoEditField.Visible = "on";
                app.DoEditFieldLabel.Visible = "on";
                app.OrdenEditField.Visible = "on";
                app.OrdenEditFieldLabel.Visible = "on";
                
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AbririmagenButton = uibutton(app.UIFigure, 'push');
            app.AbririmagenButton.ButtonPushedFcn = createCallbackFcn(app, @btnAbrir, true);
            app.AbririmagenButton.Text = 'Abrir imagen';
            app.AccionesDropDownLabel = uilabel(app.UIFigure);
            app.AccionesDropDownLabel.Visible = 'off';
            app.AccionesDropDownLabel.Text = 'Acciones';
            app.AccionesDropDown = uidropdown(app.UIFigure);
            app.AccionesDropDown.Items = {'Compresion FFT', 'Gaussiano', 'Pasabajos ideal', 'Butterworth'};
            app.AccionesDropDown.ValueChangedFcn = createCallbackFcn(app, @cambiaOpcion, true);
            app.AccionesDropDown.Visible = 'off';
            app.AccionesDropDown.Value = 'Compresion FFT';
            app.Labelimag = uilabel(app.UIFigure);
            app.Labelimag.Visible = 'off';
            app.HazloButton = uibutton(app.UIFigure, 'push');
            app.HazloButton.ButtonPushedFcn = createCallbackFcn(app, @HazAction, true);
            app.HazloButton.Visible = 'off';
            app.HazloButton.Text = 'Hazlo!';
            app.TamaoLabel = uilabel(app.UIFigure);
            app.TamaoLabel.Visible = 'off';
            app.TamaoLabel.Text = 'Tamaño : ';
            app.DoEditFieldLabel = uilabel(app.UIFigure);
            app.DoEditFieldLabel.Visible = 'off';
            app.DoEditFieldLabel.Text = 'Do';
            app.DoEditField = uieditfield(app.UIFigure, 'text');
            app.DoEditField.Visible = 'off';
            app.OrdenEditFieldLabel = uilabel(app.UIFigure);
            app.OrdenEditFieldLabel.Visible = 'off';
            app.OrdenEditFieldLabel.Text = 'Orden';
            app.OrdenEditField = uieditfield(app.UIFigure, 'text');
            app.OrdenEditField.Visible = 'off';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end